classdef MainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Neutrino3flavorOscillationswithDecoherenceLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PlotButton                      matlab.ui.control.Button
        DistancekmEditFieldLabel        matlab.ui.control.Label
        DistancekmEditField             matlab.ui.control.NumericEditField
        DiracPhaseLabel                 matlab.ui.control.Label
        DiracPhaseEditField             matlab.ui.control.NumericEditField
        HierarchySwitchLabel            matlab.ui.control.Label
        HierarchySwitch                 matlab.ui.control.Switch
        Theta23EditFieldLabel           matlab.ui.control.Label
        Theta23EditField                matlab.ui.control.NumericEditField
        Deltam31SqEditFieldLabel        matlab.ui.control.Label
        Deltam31SqEditField             matlab.ui.control.NumericEditField
        DecoherenceGammaEditFieldLabel  matlab.ui.control.Label
        DecoherenceGammaEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            % -------------------------------------------------------------
            % Get Constants
            % -------------------------------------------------------------
            % theta13 from Daya Bay Result
            sinsq_2theta_13 = 0.092;                 % [degrees] 
            sin_2theta_13 = sqrt(sinsq_2theta_13);
            theta_13 = asin(sin_2theta_13)./2;        % [radians]

            % theta 12
            theta_12_degree = 34.45;                 % [degrees]
            theta_12 = theta_12_degree .* pi./180;    % [radians]

            % Mass Eigenstate Differences
            DELTA_m21_sq = 7.54e-5;

            % -------------------------------------------------------------
            % Get Parameters from GUI
            % -------------------------------------------------------------
            distance = app.DistancekmEditField.Value;
            
            theta_23_degree = app.Theta23EditField.Value;
            theta_23 = theta_23_degree .* pi./180;
            
            DELTA_m31_sq = app.Deltam31SqEditField.Value;
            
            gamma0 = app.DecoherenceGammaEditField.Value;
            gamma = gamma0 ./ 19750.0;
            GL = gamma .* distance;
            
            
            % -------------------------------------------------------------
            % Start Calculation            
            % -------------------------------------------------------------
            % Define the Matter Potential
            Ve = 1.0 / 1900.0;
            
            % Define the Neutrino Energy (Ranging from [0,10] GeV)
            Energy = linspace(0.4, 10, 1000);

            % Define the effective Matter Potential
            A = 0.5 .* (Ve .* Energy) ./ (1.27 .* DELTA_m31_sq);
            
            % Define the Oscillation Phase
            oscPhase = (1.27 .* DELTA_m31_sq .* distance) ./ Energy;
            
            % Calculate Sine & Cosine of Mixing Angles for Probability
            % theta_13
            sin_theta_13 = sin(theta_13);
            sinSq_2theta_13 = sin(2 * theta_13).^2;
            
            % theta_23
            sinSq_theta_23 = sin(theta_23).^2;
            cosSq_theta_23 = cos(theta_23).^2;
            sin_2theta_23 = sin(2*theta_23);
            
            % theta_12
            sin_2theta_12 = sin(2*theta_12);
            sinSq_2theta_12 = sin_2theta_12.^2;
            
            % Calculate Probability Terms
            P0 =  (sinSq_2theta_13 * sinSq_theta_23) ./ (2 .* ((A - 1) .* A).^2) .* (1 - (exp(-GL) .* cos((1-A) .* 2 .* oscPhase)));
            
            plot(Energy,P0,'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Neutrino3flavorOscillationswithDecoherenceLabel = uilabel(app.UIFigure);
            app.Neutrino3flavorOscillationswithDecoherenceLabel.Text = 'Neutrino 3-flavor Oscillations with Decoherence';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Neutrino Energy (GeV)')
            ylabel(app.UIAxes, 'Probability')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DistancekmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancekmEditFieldLabel.Text = 'Distance (km)';
            app.DistancekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiracPhaseLabel = uilabel(app.UIFigure);
            app.DiracPhaseLabel.Text = 'Dirac Phase ';
            app.DiracPhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.HierarchySwitchLabel = uilabel(app.UIFigure);
            app.HierarchySwitchLabel.Text = 'Hierarchy';
            app.HierarchySwitch = uiswitch(app.UIFigure, 'slider');
            app.HierarchySwitch.Items = {'Normal', 'Inverted'};
            app.HierarchySwitch.Value = 'Normal';
            app.Theta23EditFieldLabel = uilabel(app.UIFigure);
            app.Theta23EditFieldLabel.Text = 'Theta 23';
            app.Theta23EditField = uieditfield(app.UIFigure, 'numeric');
            app.Deltam31SqEditFieldLabel = uilabel(app.UIFigure);
            app.Deltam31SqEditFieldLabel.Text = 'Delta m31 Sq';
            app.Deltam31SqEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecoherenceGammaEditFieldLabel = uilabel(app.UIFigure);
            app.DecoherenceGammaEditFieldLabel.Text = 'Decoherence Gamma';
            app.DecoherenceGammaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end